classdef StationDesignGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StationDesignDemoTab            matlab.ui.container.Tab
        CameraOptionsPanel              matlab.ui.container.Panel
        CameraNameDropDownLabel         matlab.ui.control.Label
        CameraName                      matlab.ui.control.DropDown
        NumberofCamerasEditFieldLabel   matlab.ui.control.Label
        NumCameras                      matlab.ui.control.NumericEditField
        LensFocalLengthmmEditFieldLabel  matlab.ui.control.Label
        LensFocalLength                 matlab.ui.control.NumericEditField
        CameraTiltdegEditFieldLabel     matlab.ui.control.Label
        CameraTilt                      matlab.ui.control.NumericEditField
        CameraHeadingdegLabel           matlab.ui.control.Label
        CameraHeading                   matlab.ui.control.NumericEditField
        CameraElevationmEditFieldLabel  matlab.ui.control.Label
        CameraElevation                 matlab.ui.control.NumericEditField
        CameraOverlapdegLabel           matlab.ui.control.Label
        CameraOverlap                   matlab.ui.control.NumericEditField
        SiteLocationPanel               matlab.ui.container.Panel
        LatitudeddEditFieldLabel        matlab.ui.control.Label
        Latitude                        matlab.ui.control.NumericEditField
        LongitudeddEditFieldLabel       matlab.ui.control.Label
        Longitude                       matlab.ui.control.NumericEditField
        EPSGCodeEditFieldLabel          matlab.ui.control.Label
        EpsgCode                        matlab.ui.control.NumericEditField
        PathsPanel                      matlab.ui.container.Panel
        PathtoCIRNSupportRoutinesLabel  matlab.ui.control.Label
        PathSupportRoutines             matlab.ui.control.EditField
        PathtoSaveOutputFilesEditFieldLabel  matlab.ui.control.Label
        PathOutputFiles                 matlab.ui.control.EditField
        ButtonPathtoUAVToolboxSupportRoutines  matlab.ui.control.Button
        ButtonPathtoSaveOutputFiles     matlab.ui.control.Button
        PlotCameraFootprintButton       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        GenerateKMLFilesButton          matlab.ui.control.Button
        SaveInputValuesButton           matlab.ui.control.Button
        UseInputFileCheckBox            matlab.ui.control.CheckBox
    end

    properties (Access = private)
        % All of these variables assigned their value in function PlotCameraFootprintButtonPushed(app, event).
        %   They are now available in any callback function with the prefix 'app.' added before the variable name.
        appdir = fileparts(which('StationDesignGui.mlapp'));
        olddir = addpath(fileparts(which('StationDesignGui.mlapp')));
        az;
        cxyz;
        XYZVertices;
        res;
        dcRange;
        daRange;
        dcProj;
        daProj;
        nameCam_new;
        NU_new;
        NV_new;
        ssU_new;
        ssV_new;
        NU_in;
        NV_in;
        ssU_in;
        ssV_in;
        %olap;
    end 

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(app.appdir);
            addpath(fullfile(app.appdir,'Inputs'))
            run('cameraList.m')
            for ii = 1:numel(camList)
                items{ii} = camList(ii).cname;
            end
            items{end+1} = '*New Camera*';
            app.CameraName.Items = items;
            
            addpath(fullfile(app.appdir,'GUIroutines'))
            
        end

        % Button pushed function: SaveInputValuesButton
        function SaveInputValuesButtonPushed(app, event)
            nameCam = app.CameraName.Value;
            numCam = app.NumCameras.Value;
            focalLength = app.LensFocalLength.Value;
            tilt = app.CameraTilt.Value;
            heading = app.CameraHeading.Value;
            cz = app.CameraElevation.Value;
            olap = app.CameraOverlap.Value;
            lat = app.Latitude.Value;
            lon = app.Longitude.Value;
            epsgCode = app.EpsgCode.Value;
            supportPath = app.PathSupportRoutines.Value;
            outPath = app.PathOutputFiles.Value;
            NU = app.NU_in;
            NV = app.NV_in;
            ssU = app.ssU_in;
            ssV = app.ssV_in;
            uisave({'nameCam','numCam','focalLength','tilt','heading','cz','olap','lat','lon','epsgCode','supportPath','outPath','NU','NV','ssU','ssV'},'userInputs_1')
        end

        % Button pushed function: 
        % ButtonPathtoUAVToolboxSupportRoutines
        function ButtonPathtoUAVToolboxSupportRoutinesPushed(app, event)
            supportPath = uigetdir();
            app.PathSupportRoutines.Value = supportPath;
        end

        % Button pushed function: ButtonPathtoSaveOutputFiles
        function ButtonPathtoSaveOutputFilesPushed(app, event)
            outPath = uigetdir();
            app.PathOutputFiles.Value = outPath;
        end

        % Value changed function: UseInputFileCheckBox
        function UseInputFileCheckBoxValueChanged(app, event)
            if app.UseInputFileCheckBox.Value == 1
                cd(app.appdir)
                [inputFile,inputPath]  = uigetfile();
                addpath(inputPath)
                [~,~,ext] = fileparts(inputFile);
                if strcmp(ext,'.m') == 1
                    run(inputFile)
                elseif strcmp(ext,'.mat') == 1
                    load(inputFile)
                else
                    disp('Error: Unacceptable file type.')
                end
                app.CameraName.Value = nameCam;
                app.NumCameras.Value = numCam;
                app.NU_in = NU;
                app.NV_in = NV;
                app.ssU_in = ssU;
                app.ssV_in = ssV;
                app.LensFocalLength.Value = focalLength;
                app.CameraTilt.Value = tilt;
                app.CameraHeading.Value = heading;
                app.CameraElevation.Value = cz;
                app.CameraOverlap.Value = olap;
                app.Latitude.Value = lat;
                app.Longitude.Value = lon;
                app.EpsgCode.Value = epsgCode;
                app.PathSupportRoutines.Value = supportPath;
                app.PathOutputFiles.Value = outPath;
            end
        end

        % Value changed function: CameraName
        function CameraNameValueChanged(app, event)
            run('cameraList.m')
            [bool,ind] = ismember(app.CameraName.Value, app.CameraName.Items);
            if bool == 1
                if ind == numel(camList)+1
                    %disp('Need to prompt user to enter camera specs, add ability to save')
                    prompt = {'Enter Camera Name:','Enter Width of Chip in Pixels (NU):','Enter Height of Chip in Pixels (NV):','Enter Width of Chip in mm (ssU):'};
                    title = 'New Camera Specs';
                    dims = [1 50];
                    answer = inputdlg(prompt,title,dims);
                    app.nameCam_new = answer(1);
                    app.NU_new = str2double(answer(2));
                    app.NV_new = str2double(answer(3));
                    app.ssU_new = str2double(answer(4));
                    app.ssV_new = app.ssU_new * (app.NV_new / app.NU_new);
                end
            end
        end

        % Button pushed function: PlotCameraFootprintButton
        function PlotCameraFootprintButtonPushed(app, event)
            run('cameraList.m')
            [bool,ind] = ismember(app.CameraName.Value, app.CameraName.Items);
            if bool == 1
                if ind < numel(camList)+1
                    % user chose an existing camera
                    app.NU_in = camList(ind).NU;
                    app.NV_in = camList(ind).NV;
                    app.ssU_in = camList(ind).ssU;
                    app.ssV_in = camList(ind).ssV;
                else
                    % user made a new camera
                    app.NU_in = app.NU_new;
                    app.NV_in = app.NV_new;
                    app.ssU_in = app.ssU_new;
                    app.ssV_in = app.ssV_new;
                end
                focalLength = app.LensFocalLength.Value;
                addpath(app.PathSupportRoutines.Value)
                myFOV = fov(app.ssU_in,app.ssV_in,focalLength);
                
                %% Run code that computes footprint
                numCam = app.NumCameras.Value;
                heading = app.CameraHeading.Value;
                hfov = myFOV.horizFOV;
                olap = app.CameraOverlap.Value;
                % Compute azimuths for each camera
                app.az = findCamAzi(numCam,heading,hfov,olap);
                vfov = myFOV.vertFOV;
                tilt = app.CameraTilt.Value;
                roll = 0;  % assume roll=0
                txyz = [0,0,0];  % assume footprint target is 0 (i.e. water surface)
                app.cxyz = [0,0,app.CameraElevation.Value];  % assume camera is at the origin of the x and y axes, and is only defined by camera elevation.
                [app.XYZVertices, app.res, app.dcRange, app.daRange, app.dcProj, app.daProj] = computeFootprint(hfov, vfov, app.az, tilt, roll, app.NU_in, app.NV_in, app.cxyz, txyz);
                %% Plot footprints
                cla(app.UIAxes)
                plot(app.UIAxes,app.cxyz(1,1),app.cxyz(1,2),'*k')
                hold(app.UIAxes,'on')
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                pan(app.UIAxes,'on');
                zoom(app.UIAxes,'on');
                for ii = 1:size(app.az,2) %numCam
                    plot(app.UIAxes,[app.XYZVertices{ii}(:,1); app.XYZVertices{ii}(1,1)],[app.XYZVertices{ii}(:,2) ;app.XYZVertices{ii}(1,2)])
                end
            end
        end

        % Button pushed function: GenerateKMLFilesButton
        function GenerateKMLFilesButtonPushed(app, event)
            PlotCameraFootprintButtonPushed(app)
            makeStationDesignFigures(app.PathOutputFiles.Value, app.az, app.XYZVertices, app.res, app.dcRange, app.daRange, app.dcProj, app.daProj, app.cxyz, app.Latitude.Value, app.Longitude.Value, app.EpsgCode.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StationDesignDemoTab = uitab(app.TabGroup);
            app.StationDesignDemoTab.Title = 'Station Design Demo';
            app.CameraOptionsPanel = uipanel(app.StationDesignDemoTab);
            app.CameraOptionsPanel.Title = 'Camera Options';
            app.CameraNameDropDownLabel = uilabel(app.CameraOptionsPanel);
            app.CameraNameDropDownLabel.Text = 'Camera Name';
            app.CameraName = uidropdown(app.CameraOptionsPanel);
            app.CameraName.Items = {'Cam1', 'Cam2', 'Cam3', 'Cam4', 'Cam5'};
            app.CameraName.Editable = 'on';
            app.CameraName.ValueChangedFcn = createCallbackFcn(app, @CameraNameValueChanged, true);
            app.CameraName.Value = 'Cam1';
            app.NumberofCamerasEditFieldLabel = uilabel(app.CameraOptionsPanel);
            app.NumberofCamerasEditFieldLabel.Text = 'Number of Cameras';
            app.NumCameras = uieditfield(app.CameraOptionsPanel, 'numeric');
            app.NumCameras.Limits = [1 5];
            app.NumCameras.Value = 1;
            app.LensFocalLengthmmEditFieldLabel = uilabel(app.CameraOptionsPanel);
            app.LensFocalLengthmmEditFieldLabel.Text = 'Lens Focal Length (mm)';
            app.LensFocalLength = uieditfield(app.CameraOptionsPanel, 'numeric');
            app.LensFocalLength.Limits = [0 100];
            app.CameraTiltdegEditFieldLabel = uilabel(app.CameraOptionsPanel);
            app.CameraTiltdegEditFieldLabel.Text = 'Camera Tilt (deg.)';
            app.CameraTilt = uieditfield(app.CameraOptionsPanel, 'numeric');
            app.CameraTilt.Limits = [0 90];
            app.CameraHeadingdegLabel = uilabel(app.CameraOptionsPanel);
            app.CameraHeadingdegLabel.Text = 'Camera Heading (deg.)';
            app.CameraHeading = uieditfield(app.CameraOptionsPanel, 'numeric');
            app.CameraHeading.Limits = [0 360];
            app.CameraElevationmEditFieldLabel = uilabel(app.CameraOptionsPanel);
            app.CameraElevationmEditFieldLabel.Text = 'Camera Elevation (m)';
            app.CameraElevation = uieditfield(app.CameraOptionsPanel, 'numeric');
            app.CameraElevation.Limits = [0 Inf];
            app.CameraOverlapdegLabel = uilabel(app.CameraOptionsPanel);
            app.CameraOverlapdegLabel.Text = 'Camera Overlap (deg.)';
            app.CameraOverlap = uieditfield(app.CameraOptionsPanel, 'numeric');
            app.CameraOverlap.Limits = [0 Inf];
            app.CameraOverlap.Value = 5;
            app.SiteLocationPanel = uipanel(app.StationDesignDemoTab);
            app.SiteLocationPanel.Title = 'Site Location';
            app.LatitudeddEditFieldLabel = uilabel(app.SiteLocationPanel);
            app.LatitudeddEditFieldLabel.Text = 'Latitude (dd)';
            app.Latitude = uieditfield(app.SiteLocationPanel, 'numeric');
            app.Latitude.ValueDisplayFormat = '%.8f';
            app.LongitudeddEditFieldLabel = uilabel(app.SiteLocationPanel);
            app.LongitudeddEditFieldLabel.Text = 'Longitude (dd)';
            app.Longitude = uieditfield(app.SiteLocationPanel, 'numeric');
            app.Longitude.ValueDisplayFormat = '%.8f';
            app.EPSGCodeEditFieldLabel = uilabel(app.SiteLocationPanel);
            app.EPSGCodeEditFieldLabel.Text = 'EPSG Code';
            app.EpsgCode = uieditfield(app.SiteLocationPanel, 'numeric');
            app.EpsgCode.ValueDisplayFormat = '%.0f';
            app.PathsPanel = uipanel(app.StationDesignDemoTab);
            app.PathsPanel.Title = 'Paths';
            app.PathtoCIRNSupportRoutinesLabel = uilabel(app.PathsPanel);
            app.PathtoCIRNSupportRoutinesLabel.Text = 'Path to CIRN Support Routines';
            app.PathSupportRoutines = uieditfield(app.PathsPanel, 'text');
            app.PathtoSaveOutputFilesEditFieldLabel = uilabel(app.PathsPanel);
            app.PathtoSaveOutputFilesEditFieldLabel.Text = 'Path to Save Output Files';
            app.PathOutputFiles = uieditfield(app.PathsPanel, 'text');
            app.ButtonPathtoUAVToolboxSupportRoutines = uibutton(app.PathsPanel, 'push');
            app.ButtonPathtoUAVToolboxSupportRoutines.ButtonPushedFcn = createCallbackFcn(app, @ButtonPathtoUAVToolboxSupportRoutinesPushed, true);
            app.ButtonPathtoUAVToolboxSupportRoutines.Text = '...';
            app.ButtonPathtoSaveOutputFiles = uibutton(app.PathsPanel, 'push');
            app.ButtonPathtoSaveOutputFiles.ButtonPushedFcn = createCallbackFcn(app, @ButtonPathtoSaveOutputFilesPushed, true);
            app.ButtonPathtoSaveOutputFiles.Text = '...';
            app.PlotCameraFootprintButton = uibutton(app.StationDesignDemoTab, 'push');
            app.PlotCameraFootprintButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCameraFootprintButtonPushed, true);
            app.PlotCameraFootprintButton.Text = 'Plot Camera Footprint';
            app.UIAxes = uiaxes(app.StationDesignDemoTab);
            title(app.UIAxes, 'Camera Footprint')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            app.GenerateKMLFilesButton = uibutton(app.StationDesignDemoTab, 'push');
            app.GenerateKMLFilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateKMLFilesButtonPushed, true);
            app.GenerateKMLFilesButton.Text = 'Generate KML Files';
            app.SaveInputValuesButton = uibutton(app.StationDesignDemoTab, 'push');
            app.SaveInputValuesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInputValuesButtonPushed, true);
            app.SaveInputValuesButton.Text = 'Save User Input Values';
            app.UseInputFileCheckBox = uicheckbox(app.StationDesignDemoTab);
            app.UseInputFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseInputFileCheckBoxValueChanged, true);
            app.UseInputFileCheckBox.Text = 'Do you want to use an input file?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = StationDesignGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end